classdef BeginnerChemEngChallenge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        DatafromMethanCrackingLabel  matlab.ui.control.Label
        Step2DropDown                matlab.ui.control.DropDown
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function LoadDataButtonPushed(app, event)

            %x1: Reactor temperature (degrees centigrade)                                                             '
            %x2: Rate of H2 to n-heptane (mole ratio)                                                                 '
            %x3: Contact time (seconds)                                                                               '
            %y: Conversion of n-heptane to acetylene (%)       

        end

        % Callback function: not associated with a component
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: Step2DropDown
        function Step2DropDownValueChanged(app, event)
            value = app.Step2DropDown.Value;
            
 if strcmp(value, 'Conversion of n-heptane to acetylene (%)')
          s = load('acetylene.mat')
          plot(app.UIAxes, s.y, s.x1);
          xlabel(app.UIAxes, "Conversion of n-heptane to acetylene (%)")
          ylabel(app.UIAxes, "Reactor temperature (degrees centigrade)")
          plot(app.UIAxes2, s.y, s.x2);
          xlabel(app.UIAxes2, "Conversion of n-heptane to acetylene (%)")
          ylabel(app.UIAxes2, "Rate of H2 to n-heptane (mole ratio)")
          plot(app.UIAxes3, s.y, s.x3);
          xlabel(app.UIAxes3, "Conversion of n-heptane to acetylene (%)")
          ylabel(app.UIAxes3, "Contact time (seconds) ")
    elseif strcmp(value, 'Reactor temperature (degrees centigrade)')
         s = load('acetylene.mat')
          plot(app.UIAxes, s.x1, s.y);
          xlabel(app.UIAxes, "Reactor temperature (degrees centigrade)")
          ylabel(app.UIAxes, "Conversion of n-heptane to acetylene (%)")
          plot(app.UIAxes2, s.x1, s.x2);
          xlabel(app.UIAxes2, "Reactor temperature (degrees centigrade)")
          ylabel(app.UIAxes2, "Rate of H2 to n-heptane (mole ratio)")
          plot(app.UIAxes3, s.x1, s.x3);
          xlabel(app.UIAxes3, "Reactor temperature (degrees centigrade)")
          ylabel(app.UIAxes3, "Contact time (seconds)")
    elseif strcmp(value, 'Rate of H2 to n-heptane (mole ratio)')
         s = load('acetylene.mat')
          plot(app.UIAxes, s.x2, s.y);
          xlabel(app.UIAxes, "Rate of H2 to n-heptane (mole ratio)")
          ylabel(app.UIAxes, "Conversion of n-heptane to acetylene (%)")
          plot(app.UIAxes2, s.x2, s.x1);
          xlabel(app.UIAxes2, "Rate of H2 to n-heptane (mole ratio)")
          ylabel(app.UIAxes2, "Reactor temperature (degrees centigrade)")
          plot(app.UIAxes3, s.x2, s.x3);
          xlabel(app.UIAxes3, "Rate of H2 to n-heptane (mole ratio)")
          ylabel(app.UIAxes3, "Contact time (seconds)")
    elseif strcmp(value, 'Contact time (seconds)')
         s = load('acetylene.mat')
          plot(app.UIAxes, s.x3, s.y);
          xlabel(app.UIAxes, "Contact time (seconds)")
          ylabel(app.UIAxes, "Conversion of n-heptane to acetylene (%)")
          plot(app.UIAxes2, s.x3, s.x1);
          xlabel(app.UIAxes2, "Contact time (seconds)")
          ylabel(app.UIAxes2, "Reactor temperature (degrees centigrade)")
          plot(app.UIAxes3, s.x3, s.x2);
          xlabel(app.UIAxes3, "Contact time (seconds)")
          ylabel(app.UIAxes3, "Rate of H2 to n-heptane (mole ratio)")
 end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Step2DropDown = uidropdown(app.UIFigure);
            app.Step2DropDown.Items = {'Choose X-Axis Data', 'Conversion of n-heptane to acetylene (%)', 'Reactor temperature (degrees centigrade)', 'Rate of H2 to n-heptane (mole ratio)', 'Contact time (seconds)'};
            app.Step2DropDown.ValueChangedFcn = createCallbackFcn(app, @Step2DropDownValueChanged, true);
            app.Step2DropDown.Value = 'Choose X-Axis Data';
            app.DatafromMethanCrackingLabel = uilabel(app.UIFigure);
            app.DatafromMethanCrackingLabel.Text = 'Data from Methan Cracking';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'I''m aware that X-Variables aren''t allowed to have two corresponding Y-Values. I wanted to implement the task: "By selecting one input parameter, the user wants to see all the other input parameters corresponding to that input and associated output" - this is my (probably wrong) interpretation of what that means.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeginnerChemEngChallenge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end