classdef RankineCycle_solution_fv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        CalculateButton            matlab.ui.control.Button
        CycleDropdown              matlab.ui.control.DropDown
        WorkingFluidLabel          matlab.ui.control.Label
        EfficiencySlider           matlab.ui.control.Slider
        EfficiencySliderLabel      matlab.ui.control.Label
        CondenserPressureSlider    matlab.ui.control.Slider
        CondensorPressurekPaLabel  matlab.ui.control.Label
        BoilerPressureSlider       matlab.ui.control.Slider
        BoilerPressureMPaLabel     matlab.ui.control.Label
        TSPlot                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
% Read user inputs
Pb = app.BoilerPressureSlider.Value * 1e3;  % Convert MPa to kPa
Pc = app.CondenserPressureSlider.Value; % kPa
eta_turbine = app.EfficiencySlider.Value; % Turbine efficiency

% Approximate Saturation Properties (Interpolated from Steam Tables)
T_sat_boiler = 45.45 * log(Pb) - 45.32;  % Saturation temp (°C)
T_sat_condenser = 45.45 * log(Pc) - 45.32; % Saturation temp (°C)

h_fg_boiler = 2500 - 0.5 * (T_sat_boiler - 100); % Approx latent heat (kJ/kg)
h_fg_condenser = 2500 - 0.5 * (T_sat_condenser - 100);

h1 = 200 + 2 * (T_sat_condenser - 100);  % Enthalpy of liquid at condenser exit
s1 = 3.0;  % **Fixing a better entropy starting value**

% Isentropic Pump (1 → 2)
s2 = s1; % Isentropic, so entropy remains same
h2s = h1 + (Pb - Pc) * 0.0015;  
h2 = h1 + (h2s - h1) / 0.85;  % 85% efficiency

% Boiler (2 → 3)
h3 = h2 + h_fg_boiler;  
s3 = s2 + 1.5;  % **More entropy increase to spread the T-S graph**

% Isentropic Expansion (3 → 4)
s4s = s3; % Isentropic expansion, so s4 should be ~s3
h4s = h3 - (h3 - h1);  % Ideal isentropic enthalpy drop
h4 = h3 - eta_turbine * (h3 - h1);

% Adjusted entropy at turbine exit for better spacing
s4 = s1 + (s3 - s1) * eta_turbine;  

% Efficiency Calculation
W_turbine = h3 - h4;
W_pump = h2 - h1;
Q_boiler = h3 - h2;
cycle_efficiency = (W_turbine - W_pump) / Q_boiler * 100;


% Update Efficiency Label
%app.ResultsLabel.Text = sprintf('Cycle Efficiency: %.2f%%', cycle_efficiency);

% **Fixed T-S Diagram**
s_vals = [s1, s2, s3, s4, s1];
T_vals = [T_sat_condenser, T_sat_condenser + 50, T_sat_boiler, T_sat_condenser + 20, T_sat_condenser];

% Calculate Midpoint Temperature
T_mid = (T_vals(2) + T_vals(3)) / 2;

% Define Breakpoints for the 3-Segment Path from Point 2 to Point 3
S_total = s_vals(3) - s_vals(2);

S_mid1 = s_vals(2) + 0.1 * S_total*eta_turbine;  % First segment (10%)
S_mid2 = S_mid1 + 0.6 * S_total*eta_turbine;     % Second segment (60% isothermal)

% Make the line from Point 1 to Point 4 horizontal (isothermal)
S_4_adjusted = s_vals(4);  % Keep the entropy unchanged
T_4_adjusted = T_vals(1);  % Make it isothermal at T_sat_condenser

% Updated entropy and temperature arrays
s_vals_updated = [s_vals(1), s_vals(2), S_mid1, S_mid2, s_vals(3), S_4_adjusted, s_vals(5)];
T_vals_updated = [T_vals(1), T_vals(2), T_mid, T_mid, T_vals(3), T_4_adjusted, T_vals(5)];

% Clear previous plot and redraw
cla(app.TSPlot);
hold(app.TSPlot, 'on');

% Plot Rankine Cycle with modifications
plot(app.TSPlot, s_vals_updated, T_vals_updated, '-o', 'LineWidth', 2, 'MarkerSize', 8);

% Add Number Labels to the Points
point_labels = {'1', '2', '3', '4', '5', '6', '1'};  % Midpoints don't need labels
for i = [1,2,3,4,5,6,7]  % Skip midpoints for labeling
    text(app.TSPlot, s_vals_updated(i) + 0.05, T_vals_updated(i) + 5, point_labels{i}, ...
         'FontSize', 12, 'FontWeight', 'bold', 'Color', 'red');
end

% Labels & Formatting
xlabel(app.TSPlot, 'Entropy (S)');
ylabel(app.TSPlot, 'Temperature (T)');
title(app.TSPlot, 'Rankine Cycle T-S Diagram');
grid(app.TSPlot, 'on');

% Auto Scaling
xlim(app.TSPlot, [min(s_vals_updated)-0.1, max(s_vals_updated)+0.2]);  
ylim(app.TSPlot, [min(T_vals_updated)-10, max(T_vals_updated)+30]);

hold(app.TSPlot, 'off');
state_numbers = {'1', '2', '3', '4', '5', '6', '1'};  % Cycles states
temperature_values = T_vals_updated;  % Temperature at each state
entropy_values = s_vals_updated;  % Entropy at each state

    % Update the UITable with the new values
app.UITable.Data = [state_numbers', num2cell(temperature_values'), num2cell(entropy_values')];
        end

        % Value changed function: CycleDropdown
        function CycleDropdownValueChanged(app, event)
             selectedCycle = app.CycleDropdown.Value;

            % Set default efficiency based on cycle type
            if strcmp(selectedCycle, 'Basic Rankine')
                app.EfficiencySlider.Value = 0.85;
            elseif strcmp(selectedCycle, 'Reheat Rankine')
                app.EfficiencySlider.Value = 0.9;
            elseif strcmp(selectedCycle, 'Regenerative Rankine')
                app.EfficiencySlider.Value = 0.92;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TSPlot = uiaxes(app.UIFigure);
            title(app.TSPlot, 'T-S Diagram')
            xlabel(app.TSPlot, 'Entropy (S)')
            ylabel(app.TSPlot, 'Temperature (T)')
            zlabel(app.TSPlot, 'Z')
            app.BoilerPressureMPaLabel = uilabel(app.UIFigure);
            app.BoilerPressureMPaLabel.Text = 'Boiler Pressure (MPa)';
            app.BoilerPressureSlider = uislider(app.UIFigure);
            app.BoilerPressureSlider.Limits = [1 25];
            app.BoilerPressureSlider.MajorTicks = [1 5 9 13 17 21 25];
            app.BoilerPressureSlider.MajorTickLabels = {'1', '5', '9', '13', '17', '21', '25'};
            app.BoilerPressureSlider.Value = 10;
            app.CondensorPressurekPaLabel = uilabel(app.UIFigure);
            app.CondensorPressurekPaLabel.Text = 'Condensor Pressure (kPa)';
            app.CondenserPressureSlider = uislider(app.UIFigure);
            app.CondenserPressureSlider.Limits = [10 250];
            app.CondenserPressureSlider.Value = 50;
            app.EfficiencySliderLabel = uilabel(app.UIFigure);
            app.EfficiencySliderLabel.Text = 'Efficiency';
            app.EfficiencySlider = uislider(app.UIFigure);
            app.EfficiencySlider.Limits = [0.7 1];
            app.EfficiencySlider.MajorTicks = [0.7 0.8 0.9 1];
            app.EfficiencySlider.Value = 0.85;
            app.WorkingFluidLabel = uilabel(app.UIFigure);
            app.WorkingFluidLabel.Text = 'Select Rankine cycle type';
            app.CycleDropdown = uidropdown(app.UIFigure);
            app.CycleDropdown.Items = {'Basic Rankine', 'Reheat Rankine', 'Regenerative Rankine'};
            app.CycleDropdown.ValueChangedFcn = createCallbackFcn(app, @CycleDropdownValueChanged, true);
            app.CycleDropdown.Value = 'Basic Rankine';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'States'; 'Temperature'; 'Entropy'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RankineCycle_solution_fv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end