classdef IdealGasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CalculateButton        matlab.ui.control.Button
        VariableDropdown       matlab.ui.control.DropDown
        UnknownVariableLabel   matlab.ui.control.Label
        TemperatureKEditField  matlab.ui.control.NumericEditField
        TemperatureLabel       matlab.ui.control.Label
        VolumeVEditField       matlab.ui.control.NumericEditField
        VolumeLabel            matlab.ui.control.Label
        PressurekPaEditField   matlab.ui.control.NumericEditField
        PressureLabel          matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          %function CalculateButtonPushed(app, event)
            R = 8.314; % Universal Gas Constant
            n = 1; % Assume 1 mole

            P = app.PressurekPaEditField.Value;
            V = app.VolumeVEditField.Value;
            T = app.TemperatureKEditField.Value;
            selectedVar = app.VariableDropdown.Value;

            switch selectedVar
                case "Pressure (P)"
                    P = (n * R * T) / V;
                    app.PressurekPaEditField.Value = P;
                case "Volume (V)"
                    V = (n * R * T) / P;
                    app.VolumeVEditField.Value = V;
                case "Temperature (T)"
                    T = (P * V) / (n * R);
                    app.TemperatureKEditField.Value = T;
            end

            % Plot P-V curve
            V_range = linspace(0.1, 10, 100);
            P_range = (n * R * T) ./ V_range;
            plot(app.UIAxes, V_range, P_range, 'b', 'LineWidth', 2);
            xlabel(app.UIAxes, 'Volume (L)');
            ylabel(app.UIAxes, 'Pressure (kPa)');
            title(app.UIAxes, sprintf('P-V Curve at T = %.2f K', T));
            grid(app.UIAxes, 'on');  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Isothermal view')
            xlabel(app.UIAxes, 'Volume (L)')
            ylabel(app.UIAxes, 'Pressure (kPa)')
            zlabel(app.UIAxes, 'Z')
            app.PressureLabel = uilabel(app.UIFigure);
            app.PressureLabel.Text = 'Pressure (kPa)';
            app.PressurekPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeLabel = uilabel(app.UIFigure);
            app.VolumeLabel.Text = 'Volume (V)';
            app.VolumeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnknownVariableLabel = uilabel(app.UIFigure);
            app.UnknownVariableLabel.Text = 'To Calculate';
            app.VariableDropdown = uidropdown(app.UIFigure);
            app.VariableDropdown.Items = {'Pressure (P)', 'Volume (V)', 'Temperature (T)'};
            app.VariableDropdown.Value = 'Pressure (P)';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IdealGasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end